classdef app_regressao < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        optimizeTable                matlab.ui.control.Table
        AnovaLabel                   matlab.ui.control.Label
        PlotarButton                 matlab.ui.control.Button
        NmerodePontosSpinner         matlab.ui.control.Spinner
        MaiorInclinaoButton          matlab.ui.control.Button
        TipodeModeloButtonGroup      matlab.ui.container.ButtonGroup
        QuadButton                   matlab.ui.control.RadioButton
        LinearButton                 matlab.ui.control.RadioButton
        MinimizarButton              matlab.ui.control.Button
        MaximizarButton              matlab.ui.control.Button
        FTestTable                   matlab.ui.control.Table
        CoeficientesdoModeloLabel    matlab.ui.control.Label
        ModelCoefficientsTable       matlab.ui.control.Table
        PasteTextArea                matlab.ui.control.TextArea
        ColeInputsAquiTextAreaLabel  matlab.ui.control.Label
        InitialConditionsTable       matlab.ui.control.Table
        BoundsTable                  matlab.ui.control.Table
        FactorSelectionGroup         matlab.ui.container.ButtonGroup
        ThreeFactorsRadioButton      matlab.ui.control.RadioButton
        TwoFactorsRadioButton        matlab.ui.control.RadioButton
        LimparButton                 matlab.ui.control.Button
        TabeladeinputsLabel          matlab.ui.control.Label
        InputTable                   matlab.ui.control.Table
        GerarPlanejamentoButton      matlab.ui.control.Button
        CriarModeloButton            matlab.ui.control.Button
        CondiesiniciaisLabel         matlab.ui.control.Label
        LimitesLabel                 matlab.ui.control.Label
        graficoResiduos              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Grafico 
    end
    
    methods (Access = private)

        function [outputArg1,outputArg2,outputArg3,outputArg4,outputArg5] = gerarModelo3f(app, X1, X2, X3, Y)
            %   Gera modelo baseado em matrizes de inputs e outputs
            %   Generalização do script do Corotinho, sem elementos de interface
            %   gráfica
        
            % Garantir que X1, X2, X3, Y são coluna
            X1 = X1(:);
            X2 = X2(:);
            X3 = X3(:);
            Y = Y(:);
        
            % Construindo a matriz X (incluindo o termo constante)
            X = [ones(length(X1), 1), X1, X2, X3, X1.^2, X2.^2, X3.^2, X1.*X2, X1.*X3, X2.*X3];
        
            % Passo 1: Calcular X^T * X
            XtX = X' * X;
        
            % Passo 2: Calcular X^T * Y
            XtY = X' * Y;
        
            % Passo 3: Resolver o sistema linear para encontrar os coeficientes b
            b = XtX \ XtY;
        
            %% Análise da significância dos coeficientes:
        
            % Número de observações e variáveis
            n = length(Y);
            p = size(X, 2);
        
            % Estimar os valores preditos
            Y_pred = X * b;
        
            % Calcular os resíduos
            residuals = Y - Y_pred;
        
            % Estimar o erro padrão dos resíduos
            sigma2 = sum(residuals.^2) / (n - p);
        
            % Calcular a matriz de variâncias e covariâncias dos coeficientes
            cov_matrix = sigma2 * inv(XtX);
        
            % Erro padrão dos coeficientes
            stderr = sqrt(diag(cov_matrix));
        
            % Calcular os valores t
            t_values = b ./ stderr;
        
            % Calcular os valores p para os testes t (H0: coeficiente = 0)
            p_values = 2 * (1 - tcdf(abs(t_values), n - p));
            
            %% Análise Anova
        
            % Soma dos quadrados total (SST)
            SST = sum((Y - mean(Y)).^2);
        
            % Soma dos quadrados explicada pelo modelo (SSR)
            SSR = sum((Y_pred - mean(Y)).^2);
        
            % Soma dos quadrados dos resíduos (SSE)
            SSE = sum((Y - Y_pred).^2);
        
            % Valor de R2 e R2 ajustado
            R2 = 1 - (SSE / SST);
            R2_adj = 1 - (SSE / (n - p)) / (SST / (n - 1));
        
            % Graus de liberdade
            df_total = n - 1;
            df_model = p - 1;
            df_residual = n - p;
        
            % Mean Square (MS)
            MS_model = SSR / df_model;
            MS_residual = SSE / df_residual;
        
            % Estatística F
            F_value = MS_model / MS_residual;
        
            % Valor p para o teste F (H0: Todos os coeficientes = 0)
            p_F = 1 - fcdf(F_value, df_model, df_residual);

            % Calcular valor F para cada parâmetro
            F_param = zeros(p, 1);
            for i = 2:p  
                X_reduzido = X;  % Copiar matriz X
                X_reduzido(:, i) = [];  % Remover a coluna do parâmetro i
        
                % Modelo reduzido
                b_reduzido = (X_reduzido' * X_reduzido) \ (X_reduzido' * Y);
                Y_pred_reduzido = X_reduzido * b_reduzido;
                SSE_reduzido = sum((Y - Y_pred_reduzido).^2);
        
                % Calcular valor F para o parâmetro i
                F_param(i) = ((SSE_reduzido - SSE) / 1) / MS_residual;
            end


            outputArg1 = [b stderr p_values];
            outputArg2 = [df_model, df_residual];
            outputArg3 = [F_value];
            outputArg4 = [residuals Y_pred];
            outputArg5 = F_param;
            
        end

        function [outputArg1,outputArg2,outputArg3,outputArg4,outputArg5] = gerarModelo2f(app, X1, X2, Y)
        %   Gera modelo baseado em matrizes de inputs e outputs
        %   Generalização do script do Corotinho, sem elementos de interface
        %   gráfica
        
            % Construindo a matriz X (incluindo o termo constante)
            X = [ones(length(X1), 1), X1, X2, X1.^2, X2.^2, X1.*X2];
        
            % Passo 1: Calcular X^T * X
            XtX = X' * X;
        
            % Passo 2: Calcular X^T * Y
            XtY = X' * Y;
        
            % Passo 3: Resolver o sistema linear para encontrar os coeficientes b
            b = XtX \ XtY;        
             
            %% Análise da significância dos coeficientes:
        
            % Número de observações e variáveis
            n = length(Y);
            p = size(X, 2);
        
            % Estimar os valores preditos
            Y_pred = X * b;
        
            % Calcular os resíduos
            residuals = Y - Y_pred;
        
            % Estimar o erro padrão dos resíduos
            sigma2 = sum(residuals.^2) / (n - p);
        
            % Calcular a matriz de variâncias e covariâncias dos coeficientes
            cov_matrix = sigma2 * inv(XtX);
        
            % Erro padrão dos coeficientes
            stderr = sqrt(diag(cov_matrix));
        
            % Calcular os valores t
            t_values = b ./ stderr;
        
            % Calcular os valores p para os testes t (H0: coeficiente = 0)
            p_values = 2 * (1 - tcdf(abs(t_values), n - p));
            
            %% Análise Anova
        
            % Soma dos quadrados total (SST)
            SST = sum((Y - mean(Y)).^2);
        
            % Soma dos quadrados explicada pelo modelo (SSR)
            SSR = sum((Y_pred - mean(Y)).^2);
        
            % Soma dos quadrados dos resíduos (SSE)
            SSE = sum((Y - Y_pred).^2);
        
            % Valor de R2 e R2 ajustado
            R2 = 1 - (SSE / SST);
            R2_adj = 1 - (SSE / (n - p)) / (SST / (n - 1));
        
            % Graus de liberdade
            df_total = n - 1;
            df_model = p - 1;
            df_residual = n - p;
        
            % Mean Square (MS)
            MS_model = SSR / df_model;
            MS_residual = SSE / df_residual;
        
            % Estatística F
            F_value = MS_model / MS_residual;
        
            % Valor p para o teste F (H0: Todos os coeficientes = 0)
            p_F = 1 - fcdf(F_value, df_model, df_residual);

            % Calcular valor F para cada parâmetro
            F_param = zeros(p, 1);
            for i = 2:p  
                X_reduzido = X;  % Copiar matriz X
                X_reduzido(:, i) = [];  % Remover a coluna do parâmetro i
        
                % Modelo reduzido
                b_reduzido = (X_reduzido' * X_reduzido) \ (X_reduzido' * Y);
                Y_pred_reduzido = X_reduzido * b_reduzido;
                SSE_reduzido = sum((Y - Y_pred_reduzido).^2);
        
                % Calcular valor F para o parâmetro i
                F_param(i) = ((SSE_reduzido - SSE) / 1) / MS_residual;
            end
          
         
            outputArg1 = [b stderr p_values];
            outputArg2 = [df_model, df_residual];
            outputArg3 = [F_value];
            outputArg4 = [residuals, Y_pred];
            outputArg5 = F_param;
        end
        

        function [outputArg1,outputArg2,outputArg3,outputArg4,outputArg5] = gerarModeloLin2f(app, X1, X2, Y)
        %   Gera modelo baseado em matrizes de inputs e outputs
        %   Generalização do script do Corotinho, sem elementos de interface
        %   gráfica
        
            % Construindo a matriz X (incluindo o termo constante)
            X = [ones(length(X1), 1), X1, X2];
        
            % Passo 1: Calcular X^T * X
            XtX = X' * X;
        
            % Passo 2: Calcular X^T * Y
            XtY = X' * Y;
        
            % Passo 3: Resolver o sistema linear para encontrar os coeficientes b
            b = XtX \ XtY;        
             
            %% Análise da significância dos coeficientes:
        
            % Número de observações e variáveis
            n = length(Y);
            p = size(X, 2);
        
            % Estimar os valores preditos
            Y_pred = X * b;
        
            % Calcular os resíduos
            residuals = Y - Y_pred;
        
            % Estimar o erro padrão dos resíduos
            sigma2 = sum(residuals.^2) / (n - p);
        
            % Calcular a matriz de variâncias e covariâncias dos coeficientes
            cov_matrix = sigma2 * inv(XtX);
        
            % Erro padrão dos coeficientes
            stderr = sqrt(diag(cov_matrix));
        
            % Calcular os valores t
            t_values = b ./ stderr;
        
            % Calcular os valores p para os testes t (H0: coeficiente = 0)
            p_values = 2 * (1 - tcdf(abs(t_values), n - p));
            
            %% Análise Anova
        
            % Soma dos quadrados total (SST)
            SST = sum((Y - mean(Y)).^2);
        
            % Soma dos quadrados explicada pelo modelo (SSR)
            SSR = sum((Y_pred - mean(Y)).^2);
        
            % Soma dos quadrados dos resíduos (SSE)
            SSE = sum((Y - Y_pred).^2);
        
            % Valor de R2 e R2 ajustado
            R2 = 1 - (SSE / SST);
            R2_adj = 1 - (SSE / (n - p)) / (SST / (n - 1));
        
            % Graus de liberdade
            df_total = n - 1;
            df_model = p - 1;
            df_residual = n - p;
        
            % Mean Square (MS)
            MS_model = SSR / df_model;
            MS_residual = SSE / df_residual;
        
            % Estatística F
            F_value = MS_model / MS_residual;
        
            % Valor p para o teste F (H0: Todos os coeficientes = 0)
            p_F = 1 - fcdf(F_value, df_model, df_residual);

            % Calcular valor F para cada parâmetro
            F_param = zeros(p, 1);
            for i = 2:p  
                X_reduzido = X;  % Copiar matriz X
                X_reduzido(:, i) = [];  % Remover a coluna do parâmetro i
        
                % Modelo reduzido
                b_reduzido = (X_reduzido' * X_reduzido) \ (X_reduzido' * Y);
                Y_pred_reduzido = X_reduzido * b_reduzido;
                SSE_reduzido = sum((Y - Y_pred_reduzido).^2);
        
                % Calcular valor F para o parâmetro i
                F_param(i) = ((SSE_reduzido - SSE) / 1) / MS_residual;
            end
          
         
            outputArg1 = [b stderr p_values];
            outputArg2 = [df_model, df_residual];
            outputArg3 = [F_value];
            outputArg4 = [residuals, Y_pred];
            outputArg5 = F_param;
        
        end

        function maximize2f(app, b, IC, lower_bounds, upper_bounds)
            % Coeficientes da função (usando os valores obtidos em b)
            b0 = b(1);
            b1 = b(2);
            b2 = b(3);
            b3 = b(4);
            b4 = b(5);
            b5 = b(6);
        
            % Função anônima para o modelo
            model_func = @(c) (-1)*(b0 + b1*c(1) + b2*c(2) + b3*c(1).^2 + b4*c(2).^2 + b5*c(1).*c(2));

            % Encontrando o ponto ótimo com restrições
            options = optimoptions('fmincon','Display','iter'); % Opcional: para ver o progresso
            [optimal_point, optimal_Y] = fmincon(model_func, IC, [], [], [], [], lower_bounds, upper_bounds, [], options);

            % Mostrar ponto ótimo na tabela
            app.optimizeTable.ColumnName = {'X1', 'X2', 'Y'};
            app.optimizeTable.Data = [optimal_point(1), optimal_point(2), (-1)*model_func(optimal_point)];
        end

        function minimize2f(app, b, IC, lower_bounds, upper_bounds)
            % Coeficientes da função (usando os valores obtidos em b)
            b0 = b(1);
            b1 = b(2);
            b2 = b(3);
            b3 = b(4);
            b4 = b(5);
            b5 = b(6);
        
            % Função anônima para o modelo
            model_func = @(c) (b0 + b1*c(1) + b2*c(2) + b3*c(1).^2 + b4*c(2).^2 + b5*c(1).*c(2));% Coeficientes da função (usando os valores obtidos em b)
            
            % Encontrando o ponto ótimo com restrições
            options = optimoptions('fmincon','Display','iter'); % Opcional: para ver o progresso
            [optimal_point, optimal_Y] = fmincon(model_func, IC, [], [], [], [], lower_bounds, upper_bounds, [], options);

            % Mostrar ponto ótimo na tabela
            app.optimizeTable.ColumnName = {'X1', 'X2', 'Y'};
            app.optimizeTable.Data = [optimal_point(1), optimal_point(2), model_func(optimal_point)];
        end
        function minimize3f(app, b, IC, lower_bounds, upper_bounds)
            % Coeficientes da função (usando os valores obtidos em b)
            b0 = b(1);
            b1 = b(2);
            b2 = b(3);
            b3 = b(4);
            b4 = b(5);
            b5 = b(6);
            b6 = b(7);
            b7 = b(8);
            b8 = b(9);
            b9 = b(10);
        
            % Função anônima para o modelo
            model_func = @(c) (b0 + b1*c(1) + b2*c(2) + b3*c(3) + b4*c(1).^2 + b5*c(2).^2 + b6*c(3).^2 + b7*c(1).*c(2) + b8*c(1).*c(3) + b9*c(2).*c(3));% Coeficientes da função (usando os valores obtidos em b)
            
            % Encontrando o ponto ótimo com restrições
            options = optimoptions('fmincon','Display','iter'); % Opcional: para ver o progresso
            [optimal_point, optimal_Y] = fmincon(model_func, IC, [], [], [], [], lower_bounds, upper_bounds, [], options);

           % Mostrar ponto ótimo na tabela
            app.optimizeTable.ColumnName = {'X1', 'X2', 'X3', 'Y'};
            app.optimizeTable.Data = [optimal_point(1), optimal_point(2), optimal_point(3), model_func(optimal_point)];

        end

        function maximize3f(app, b, IC, lower_bounds, upper_bounds)
            % Coeficientes da função (usando os valores obtidos em b)
            b0 = b(1);
            b1 = b(2);
            b2 = b(3);
            b3 = b(4);
            b4 = b(5);
            b5 = b(6);
            b6 = b(7);
            b7 = b(8);
            b8 = b(9);
            b9 = b(10);
        
            % Função anônima para o modelo
            model_func = @(c) (-1)*(b0 + b1*c(1) + b2*c(2) + b3*c(3) + b4*c(1).^2 + b5*c(2).^2 + b6*c(3).^2 + b7*c(1).*c(2) + b8*c(1).*c(3) + b9*c(2).*c(3));% Coeficientes da função (usando os valores obtidos em b)
            
            % Encontrando o ponto ótimo com restrições
            options = optimoptions('fmincon','Display','iter'); % Opcional: para ver o progresso
            [optimal_point, optimal_Y] = fmincon(model_func, IC, [], [], [], [], lower_bounds, upper_bounds, [], options);

           % Mostrar ponto ótimo na tabela
            app.optimizeTable.ColumnName = {'X1', 'X2', 'X3', 'Y'};
            app.optimizeTable.Data = [optimal_point(1), optimal_point(2), optimal_point(3), (-1)*model_func(optimal_point)];

        end

        
        function plotModelSurface2f(app, coef_details, lower_bounds, upper_bounds)
            
            % Cria uma grade de pontos usando os limites inferiores e superiores
            [X1Grid, X2Grid] = meshgrid(linspace(lower_bounds(1), upper_bounds(1), 50), ...
                                        linspace(lower_bounds(2), upper_bounds(2), 50));
    
            % Avalia a função do modelo nos pontos da grade
            YGrid = coef_details(1) + coef_details(2)*X1Grid + coef_details(3)*X2Grid + ...
                    coef_details(4)*X1Grid.^2 + coef_details(5)*X2Grid.^2 + coef_details(6)*X1Grid.*X2Grid;

            % Toma limite superior e inferior 
            Ysup = max(max(YGrid));
            Yinf = min(min(YGrid));

            % Toma níveis para as curvas de nível
            resolution = 10;
            levels = linspace(Yinf, Ysup, resolution)';
    
            % Plotagem da superfície na nova janela
            surf(app.Grafico, X1Grid, X2Grid, YGrid);
            xlabel(app.Grafico, 'X1');
            ylabel(app.Grafico, 'X2');
            zlabel(app.Grafico, 'Y');
            title(app.Grafico, 'Superfície de resposta gerada pelo modelo');
            colormap(app.Grafico, 'hsv');
            hold(app.Grafico, 'on'); % Mantém o gráfico atual para adicionar mais elementos gráficos
            contour3(app.Grafico, X1Grid, X2Grid, YGrid, levels, 'k', 'LineWidth', 2);
            hold(app.Grafico, 'off')
        end

        function plotModelSurfaceLin2f(app, coef_details, lower_bounds, upper_bounds)

            % Cria uma grade de pontos usando os limites inferiores e superiores
            [X1Grid, X2Grid] = meshgrid(linspace(lower_bounds(1), upper_bounds(1), 50), ...
                                        linspace(lower_bounds(2), upper_bounds(2), 50));
    
            % Avalia a função do modelo nos pontos da grade
            YGrid = coef_details(1) + coef_details(2)*X1Grid + coef_details(3)*X2Grid;

            % Toma limite superior e inferior 
            Ysup = max(max(YGrid));
            Yinf = min(min(YGrid));

            % Toma níveis para as curvas de nível
            resolution = 10;
            levels = linspace(Yinf, Ysup, resolution)';
    
            % Plotagem da superfície no componente UIAxes
            surf(app.Grafico, X1Grid, X2Grid, YGrid);
            xlabel(app.Grafico, 'X1');
            ylabel(app.Grafico, 'X2');
            zlabel(app.Grafico, 'Y');
            title(app.Grafico, 'Superfície de resposta gerada pelo modelo');
            colormap(app.Grafico, 'hsv');
            hold(app.Grafico, 'on'); % Mantém o gráfico atual para adicionar mais elementos gráficos
            contour3(app.Grafico, X1Grid, X2Grid, YGrid, levels, 'k', 'LineWidth', 2);
            hold(app.Grafico, 'off')
        end

        function plotResiduals(app, Residuals)
            if min(Residuals(:,2)) < 0
                a = 2*min(Residuals(:,2));
            else
                a = -1*max(Residuals(:,2));
            end
            if max(Residuals(:,2)) > 0
                b = 2*max(Residuals(:,2));
            else
                b = -1*min(Residuals(:,2));
            end
            A = [a, b];
            B = [0 0];

            % Plotar os pontos no componente graficoResiduos
            cla(app.graficoResiduos); % Limpar o gráfico existente
            hold(app.graficoResiduos, 'on');
            plot(app.graficoResiduos, Residuals(:,2), Residuals(:,1) , 'r.', 'MarkerSize', 8);
            plot(app.graficoResiduos, A, B, 'b');
            hold(app.graficoResiduos, 'off')

            xlabel(app.graficoResiduos, 'Y');
            ylabel(app.graficoResiduos, 'Resíduos');
            grid(app.graficoResiduos, 'on');
        end

        function updateModelCoefficientsTable(app, coef_details, num_factors)
            % coef_details: Matriz contendo [coeficiente, erro padrão, valor p]
            % num_factors: Número de fatores (2 ou 3)
    
            if num_factors == 2
                % Para 2 fatores, temos 6 coeficientes (b0, b1, b2, b3, b4, b5)
                app.ModelCoefficientsTable.ColumnName = {'b0', 'X1', 'X2', 'X1^2', 'X2^2', 'X1*X2'};
                app.ModelCoefficientsTable.RowName = {'Coeficientes', 'Erro Padrão', 'p'};
                app.ModelCoefficientsTable.Data = [
                    coef_details(1:6, 1)';  % Coeficientes
                    coef_details(1:6, 2)';  % Erro padrão
                    coef_details(1:6, 3)';  % Valor p
                ];
                
            elseif num_factors == 3
                % Para 3 fatores, temos 10 coeficientes (b0, b1, b2, b3, b4, b5, b6, b7, b8, b9)
                app.ModelCoefficientsTable.ColumnName = {'b0', 'X1', 'X2', 'X3', 'X1^2', 'X2^2', 'X3^2', 'X1*X2', 'X1*X3', 'X2*X3'};
                app.ModelCoefficientsTable.RowName = {'Coeficientes', 'Erro Padrão', 'p'};
                app.ModelCoefficientsTable.Data = [
                    coef_details(1:10, 1)';  % Coeficientes
                    coef_details(1:10, 2)';  % Erro padrão
                    coef_details(1:10, 3)';  % Valor p
                ];
            end
        end

        function updateLinModelCoefficientsTable(app, coef_details, num_factors)
            % coef_details: Matriz contendo [coeficiente, erro padrão, valor p]
            % num_factors: Número de fatores (2 ou 3)
    
            if num_factors == 2
                % Para 2 fatores, temos 3 coeficientes (b0, b1, b2)
                app.ModelCoefficientsTable.ColumnName = {'b0', 'X1', 'X2'};
                app.ModelCoefficientsTable.RowName = {'Coeficientes', 'Erro Padrão', 'p'};
                app.ModelCoefficientsTable.Data = [
                    coef_details(1:3, 1)';  % Coeficientes
                    coef_details(1:3, 2)';  % Erro padrão
                    coef_details(1:3, 3)';  % Valor p
                ];
                
            elseif num_factors == 3
                % Para 3 fatores, temos 4 coeficientes (b0, b1, b2, b3, b4)
                app.ModelCoefficientsTable.ColumnName = {'b0', 'X1', 'X2', 'X3'};
                app.ModelCoefficientsTable.RowName = {'Coeficientes', 'Erro Padrão', 'p'};
                app.ModelCoefficientsTable.Data = [
                    coef_details(1:4, 1)';  % Coeficientes
                    coef_details(1:4, 2)';  % Erro padrão
                    coef_details(1:4, 3)';  % Valor p
                ];
            end
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CriarModeloButton
        function CriarModeloButtonPushed2(app, event)
        
        app.PlotarButton.Visible = 'on';    

        % Obter as condições iniciais da tabela
        IC = app.InitialConditionsTable.Data;

        % Obter os limites inferiores e superiores da tabela
        lower_bounds = app.BoundsTable.Data(1, :);  % Limites inferiores
        upper_bounds = app.BoundsTable.Data(2, :);  % Limites superiores
   

        % Obter os valores da tabela (X1, X2, [X3]) e Y
        data = app.InputTable.Data;

        selectedButton = app.FactorSelectionGroup.SelectedObject;
        selectedModel = app.TipodeModeloButtonGroup.SelectedObject;

        if selectedButton == app.TwoFactorsRadioButton
            X1 = data(:, 1); % Coluna X1
            X2 = data(:, 2); % Coluna X2
            Y = data(:, 3);  % Coluna Y
        
            % Chamar a função para 2 fatores
            if selectedModel == app.QuadButton
                [coef_details, df, F_test, residuals, F_param] = gerarModelo2f(app, X1, X2, Y);
                
                % Mostrar coeficientes
                updateModelCoefficientsTable(app, coef_details, 2);
                app.MinimizarButton.Visible = 'on';
                app.MaximizarButton.Visible = 'on';
                app.MaiorInclinaoButton.Visible = 'off';
                app.NmerodePontosSpinner.Visible = 'off';

                % Mostra valores para o teste F
                app.AnovaLabel.Visible = 'on';
                app.FTestTable.Visible = 'on';
                app.FTestTable.ColumnName = {'Valor F', 'Valor Crítico'};
                app.FTestTable.RowName = {'Modelo', 'X1', 'X2', 'X1^2', 'X2^2', 'X1*X2'};
                app.FTestTable.Data = [F_test(1) finv(0.95, df(1), df(2)); F_param(2) finv(0.95, 1, df(2)); F_param(3) finv(0.95, 1, df(2)); ...
                    F_param(4) finv(0.95, 1, df(2)); F_param(5) finv(0.95, 1, df(2)); F_param(6) finv(0.95, 1, df(2))];
        
            elseif selectedModel == app.LinearButton
                [coef_details, df, F_test, residuals, F_param] = gerarModeloLin2f(app, X1, X2, Y);
                
                % Mostrar coeficientes
                updateLinModelCoefficientsTable(app, coef_details, 2);
                app.MinimizarButton.Visible = 'off';
                app.MaximizarButton.Visible = 'off';
                app.MaiorInclinaoButton.Visible = 'on';
                app.NmerodePontosSpinner.Visible = 'on';

                % Mostra valores para o teste F
                app.AnovaLabel.Visible = 'on';
                app.FTestTable.Visible = 'on';
                app.FTestTable.ColumnName = {'Valor F', 'Valor Crítico'};
                app.FTestTable.RowName = {'Modelo', 'X1', 'X2'};
                app.FTestTable.Data = [F_test(1) finv(0.95, df(1), df(2)); F_param(2) finv(0.95, 1, df(2)); F_param(3) finv(0.95, 1, df(2))];
           
            end
            
            % Plota resíduos
            plotResiduals(app, residuals);


        elseif selectedButton == app.ThreeFactorsRadioButton
            X1 = data(:, 1); % Coluna X1
            X2 = data(:, 2); % Coluna X2
            X3 = data(:, 3); % Coluna X3
            Y = data(:, 4);  % Coluna Y
        
            % Chamar a função para 3 fatores
            [coef_details, df, F_test, residuals, F_param] = gerarModelo3f(app, X1, X2, X3, Y);
            app.MinimizarButton.Visible = 'on';
            app.MaximizarButton.Visible = 'on';
            app.MaiorInclinaoButton.Visible = 'off';
            app.NmerodePontosSpinner.Visible = 'off';
          
            updateModelCoefficientsTable(app, coef_details, 3);
            
            % Plota resíduos
            plotResiduals(app, residuals);

            % Mostra valores para o teste F
            app.FTestTable.ColumnName = {'Valor F', 'Valor Crítico'};
            app.FTestTable.RowName = {'Modelo', 'X1', 'X2', 'X3', 'X1^2', 'X2^2', 'X3^2', 'X1*X2', 'X1*X3', 'X2*X3'};
            app.FTestTable.Data = [F_test(1) finv(0.95, df(1), df(2));F_param(2) finv(0.95, 1, df(2));F_param(3) finv(0.95, 1, df(2));...
                F_param(4) finv(0.95, 1, df(2));F_param(5) finv(0.95, 1, df(2)); F_param(6) finv(0.95, 1, df(2)); F_param(7) finv(0.95, 1, df(2));...
                F_param(8) finv(0.95, 1, df(2)); F_param(9) finv(0.95, 1, df(2)); F_param(10) finv(0.95, 1, df(2))];

        end
        end

        % Button pushed function: GerarPlanejamentoButton
        function GerarPlanejamentoButtonPushed(app, event)
       % Obter os limites inferiores e superiores da tabela
        lower_bounds = app.BoundsTable.Data(1, :);  % Limites inferiores
        upper_bounds = app.BoundsTable.Data(2, :);  % Limites superiores

        % Verificar se os limites são NaN
        if any(isnan(lower_bounds)) || any(isnan(upper_bounds))
            uialert(app.UIFigure, 'Verifique os limites inseridos. Os limites devem ser números válidos.', 'Erro nos Limites');
            return;
        end
        
        % Obter as condições iniciais da tabela
        IC = app.InitialConditionsTable.Data;
        IC = (lower_bounds + upper_bounds) / 2;

        selectedButton = app.FactorSelectionGroup.SelectedObject;
        
        if selectedButton == app.TwoFactorsRadioButton
            % Calcular os pontos ao longo das bordas (8 pontos em um quadrado)
            a = (upper_bounds(1) - lower_bounds(1))/2;
            b = (upper_bounds(2) - lower_bounds(2))/2;
            am = (upper_bounds(1) + lower_bounds(1))/2;
            bm = (upper_bounds(2) + lower_bounds(2))/2;
            star_points = [
                am - a/sqrt(2), bm - b/sqrt(2);
                am + a/sqrt(2), bm - b/sqrt(2);
                am - a/sqrt(2), bm + b/sqrt(2);
                am + a/sqrt(2), bm + b/sqrt(2);
                IC(1), lower_bounds(2);
                IC(1), upper_bounds(2);
                lower_bounds(1), IC(2);
                upper_bounds(1), IC(2);
            ];
    
            % Triplicar o ponto central
            center_points_triplicados = repmat(IC, 3, 1);
    
            % Plotar os pontos no componente UIAxes
            ...
        elseif selectedButton == app.ThreeFactorsRadioButton
            a = (upper_bounds(1) - lower_bounds(1))/2;
            b = (upper_bounds(2) - lower_bounds(2))/2;
            c = (upper_bounds(3) - lower_bounds(3))/2;
            a2 = (1/sqrt(3))*a;
            b2 = (1/sqrt(3))*b;
            c2 = (1/sqrt(3))*c;
            am = (upper_bounds(1) + lower_bounds(1))/2;
            bm = (upper_bounds(2) + lower_bounds(2))/2;
            cm = (upper_bounds(3) + lower_bounds(3))/2;
            star_points = [
                am + a, bm, cm;
                am - a, bm, cm;
                am, bm + b, cm;
                am, bm - b, cm;
                am, bm, cm + c,
                am, bm, cm - c;
                am + a, bm + b, cm + c;
                am - a, bm + b, cm + c;
                am + a, bm - b, cm + c;
                am + a, bm + b, cm - c;
                am - a, bm - b, cm + c;
                am - a, bm + b, cm - c;
                am + a, bm - b, cm - c;
                am - a, bm - b, cm - c;
            ];
            % Triplicar o ponto central
            center_points_triplicados = repmat(IC, 3, 1);
    
           ...
        end
        
        end

        % Selection changed function: FactorSelectionGroup
        function FactorSelectionGroupSelectionChanged(app, event)
            selectedButton = app.FactorSelectionGroup.SelectedObject;
                
            if selectedButton == app.TwoFactorsRadioButton
            % Configurar a tabela para 2 fatores (X1 e X2) + Y
            app.InputTable.ColumnName = {'X1', 'X2', 'Y'};
            app.InputTable.Data = zeros(11, 3); % Ajustar número de colunas para 3
            % Configurar a tabela de condições iniciais para 2 fatores (X1 e X2)
            app.InitialConditionsTable.ColumnName = {'X1', 'X2'};
            app.InitialConditionsTable.Data = zeros(1, 2); % 1 linha para condições iniciais
            app.BoundsTable.ColumnName = {'X1', 'X2'};
            app.BoundsTable.RowName = {'Inferiores', 'Superiores'};
            app.BoundsTable.Data = zeros(2, 2); % 2 linhas: 1 para limites inferiores, 1 para superiores
            elseif selectedButton == app.ThreeFactorsRadioButton
            % Configurar a tabela para 3 fatores (X1, X2 e X3) + Y
            app.InputTable.ColumnName = {'X1', 'X2', 'X3', 'Y'};
            app.InputTable.Data = zeros(17, 4); % Ajustar número de colunas para 4
            % Configurar a tabela de condições iniciais para 3 fatores (X1, X2 e X3)
            app.InitialConditionsTable.ColumnName = {'X1', 'X2', 'X3'};
            app.InitialConditionsTable.Data = zeros(1, 3); % 1 linha para condições iniciais
            % Configurar a tabela de limites para 3 fatores (X1, X2 e X3)
            app.BoundsTable.ColumnName = {'X1', 'X2', 'X3'};
            app.BoundsTable.RowName = {'Inferiores', 'Superiores'};
            app.BoundsTable.Data = zeros(2, 3); % 2 linhas: 1 para limites inferiores, 1 para superiores
            end
        
       
        end

        % Value changed function: PasteTextArea
        function PasteTextAreaValueChanged(app, event)
           % Obter o texto colado no TextArea
            pastedText = app.PasteTextArea.Value;

            % Converter o cell array em uma única string
            pastedText = strjoin(pastedText, '\n');
            
            % Quebrar o texto colado em linhas e colunas
            rows = strsplit(pastedText, '\n'); % Separar por linhas
            numRows = length(rows);
            
            % Preparar a tabela de dados
            data = [];
            
            for i = 1:numRows
                % Quebrar cada linha em colunas usando separador de tabulação ou vírgula
                cols = strsplit(rows{i}, {'\t', ','});  
                
                % Converter os valores de string para número
                numericCols = str2double(cols);
                
                % Adicionar os valores à tabela de dados
                data = [data; numericCols];
            end
            
            % Verificar o tamanho dos dados e ajustar a tabela de inputs
            if size(data, 2) == 2
                app.InputTable.ColumnName = {'X1', 'X2'};
            elseif size(data, 2) == 3
                app.InputTable.ColumnName = {'X1', 'X2', 'X3'};
            end
            
            % Preencher a tabela de inputs com os dados processados
            app.InputTable.Data = data;
            
        end

        % Button pushed function: MinimizarButton
        function MinimizarButtonPushed(app, event)
            selectedButton = app.FactorSelectionGroup.SelectedObject;
            if selectedButton == app.TwoFactorsRadioButton
                % Obter as condições iniciais da tabela
                IC = app.InitialConditionsTable.Data;

                if isempty(IC)
                    IC = [mean(X1), mean(X2)];
                end
            
                % Obter os limites inferiores e superiores da tabela
                lower_bounds = app.BoundsTable.Data(1, :);  % Limites inferiores
                upper_bounds = app.BoundsTable.Data(2, :);  % Limites superiores

                b = app.ModelCoefficientsTable.Data(1, :);
                b = b(:);

                minimize2f(app, b , IC, lower_bounds, upper_bounds)
            elseif selectedButton == app.ThreeFactorsRadioButton
                IC = app.InitialConditionsTable.Data;

                if isempty(IC)
                    IC = [mean(X1), mean(X2), mean(X3)];
                end
            
                % Obter os limites inferiores e superiores da tabela
                lower_bounds = app.BoundsTable.Data(1, :);  % Limites inferiores
                upper_bounds = app.BoundsTable.Data(2, :);  % Limites superiores

                b = app.ModelCoefficientsTable.Data(1, :);
                b = b(:);
                minimize3f(app, b , IC, lower_bounds, upper_bounds);

            end
        end

        % Button pushed function: MaximizarButton
        function MaximizarButtonPushed(app, event)
            selectedButton = app.FactorSelectionGroup.SelectedObject;
            if selectedButton == app.TwoFactorsRadioButton
                % Obter as condições iniciais da tabela
                IC = app.InitialConditionsTable.Data;

                if isempty(IC)
                    IC = [mean(X1), mean(X2)];
                end
            
                % Obter os limites inferiores e superiores da tabela
                lower_bounds = app.BoundsTable.Data(1, :);  % Limites inferiores
                upper_bounds = app.BoundsTable.Data(2, :);  % Limites superiores

                b = app.ModelCoefficientsTable.Data(1, :);
                b = b(:);

                maximize2f(app, b , IC, lower_bounds, upper_bounds)
            else if selectedButton == app.ThreeFactorsRadioButton
                % Obter as condições iniciais da tabela
                IC = app.InitialConditionsTable.Data;

                if isempty(IC)
                    IC = [mean(X1), mean(X2)];
                end
            
                % Obter os limites inferiores e superiores da tabela
                lower_bounds = app.BoundsTable.Data(1, :);  % Limites inferiores
                upper_bounds = app.BoundsTable.Data(2, :);  % Limites superiores

                b = app.ModelCoefficientsTable.Data(1, :);
                b = b(:);

                maximize3f(app, b , IC, lower_bounds, upper_bounds)
            end

            end
        end

        % Button pushed function: MaiorInclinaoButton
        function MaiorInclinaoButtonPushed(app, event)
            % Obter os coeficientes do modelo e transformar em vetor coluna
            b = app.ModelCoefficientsTable.Data(1, :);
            b = b(:);  % Coeficientes: [b0, b1, b2]
        
            % Definir a função do modelo
            model_func = @(X) b(1) + b(2)*X(1) + b(3)*X(2);
        
            % Obter limites inferiores e superiores da tabela
            lower_bounds = app.BoundsTable.Data(1, :);
            upper_bounds = app.BoundsTable.Data(2, :);
        
            % Definir condições iniciais
            IC = app.InitialConditionsTable.Data;
            if isempty(IC)
                IC = (lower_bounds + upper_bounds) / 2;
            end
        
            % Resolução para o número de pontos
            resolution = app.NmerodePontosSpinner.Value;
        
            % Calcular o gradiente
            grad = [b(2), b(3)];
            
            % Verificar se o gradiente é zero
            if norm(grad) == 0
                uialert(app.UIFigure, 'O gradiente é zero, não há inclinação.', 'Erro');
                return;
            end
        
            % Normalizar o gradiente para definir a direção
            grad_norm = grad / norm(grad);
        
            % Calcular o tamanho do passo
            step_size = min((upper_bounds - IC) ./ grad_norm) / resolution;
        
            % Plotar a trajetória ascendente
            hold(app.Grafico, 'on');
            X = IC;
            plot3(app.Grafico, X(1), X(2), model_func(X), 'ro', 'MarkerSize', 8, 'LineWidth', 2);
            for i = 1:resolution
                X = X + step_size * grad_norm;
                % Garantir que o ponto não exceda os limites
                if any(X < lower_bounds) || any(X > upper_bounds)
                    break;
                end
                plot3(app.Grafico, X(1), X(2), model_func(X), 'b*', 'MarkerSize', 8);
            end
        
            % Voltar às condições iniciais e plotar a trajetória descendente
            X = IC;
            for i = 1:resolution
                X = X - step_size * grad_norm;
                % Garantir que o ponto não exceda os limites
                if any(X < lower_bounds) || any(X > upper_bounds)
                    break;
                end
                plot3(app.Grafico, X(1), X(2), model_func(X), 'b*', 'MarkerSize', 8);
            end
    
            hold(app.Grafico, 'off');
            
        end

        % Button pushed function: PlotarButton
        function PlotarButtonPushed(app, event)
            
            % Criar uma nova janela para o gráfico
            graficoFig = uifigure('Name', 'Gráfico de Superfície', 'Position', [100, 100, 600, 500]);

            % Adicionar um UIAxes na nova janela
            app.Grafico = uiaxes(graficoFig);
            app.Grafico.Position = [50, 50, 500, 400];  % Ajustar a posição e tamanho do gráfico
            
            coef_details = app.ModelCoefficientsTable.Data(1,:);

            % Obter os limites inferiores e superiores da tabela
            lower_bounds = app.BoundsTable.Data(1, :);  % Limites inferiores
            upper_bounds = app.BoundsTable.Data(2, :);  % Limites superiores
            
            selectedButton = app.FactorSelectionGroup.SelectedObject;
            selectedModel = app.TipodeModeloButtonGroup.SelectedObject;
            if selectedButton == app.TwoFactorsRadioButton   
                if selectedModel == app.QuadButton
                    plotModelSurface2f(app, coef_details, lower_bounds, upper_bounds);
                else if selectedModel == app.LinearButton
                    plotModelSurfaceLin2f(app, coef_details, lower_bounds, upper_bounds);
                end
                end
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.graficoResiduos = uiaxes(app.UIFigure);
            title(app.graficoResiduos, 'Resíduos')
            xlabel(app.graficoResiduos, 'Y')
            ylabel(app.graficoResiduos, 'Resíduos')
            zlabel(app.graficoResiduos, 'Z')
            app.LimitesLabel = uilabel(app.UIFigure);
            app.LimitesLabel.Text = 'Limites:';
            app.CondiesiniciaisLabel = uilabel(app.UIFigure);
            app.CondiesiniciaisLabel.Text = 'Condições iniciais:';
            app.CriarModeloButton = uibutton(app.UIFigure, 'push');
            app.CriarModeloButton.ButtonPushedFcn = createCallbackFcn(app, @CriarModeloButtonPushed2, true);
            app.CriarModeloButton.Text = 'Criar Modelo';
            app.GerarPlanejamentoButton = uibutton(app.UIFigure, 'push');
            app.GerarPlanejamentoButton.ButtonPushedFcn = createCallbackFcn(app, @GerarPlanejamentoButtonPushed, true);
            app.GerarPlanejamentoButton.Text = 'Gerar Planejamento';
            app.InputTable = uitable(app.UIFigure);
            app.InputTable.ColumnName = '';
            app.InputTable.RowName = {};
            app.InputTable.ColumnEditable = true;
            app.TabeladeinputsLabel = uilabel(app.UIFigure);
            app.TabeladeinputsLabel.Text = 'Tabela de inputs ';
            app.LimparButton = uibutton(app.UIFigure, 'push');
            app.LimparButton.Text = 'Limpar';
            app.FactorSelectionGroup = uibuttongroup(app.UIFigure);
            app.FactorSelectionGroup.SelectionChangedFcn = createCallbackFcn(app, @FactorSelectionGroupSelectionChanged, true);
            app.FactorSelectionGroup.Title = 'Fatores';
            app.TwoFactorsRadioButton = uiradiobutton(app.FactorSelectionGroup);
            app.TwoFactorsRadioButton.Text = '2 fatores';
            app.TwoFactorsRadioButton.Value = true;
            app.ThreeFactorsRadioButton = uiradiobutton(app.FactorSelectionGroup);
            app.ThreeFactorsRadioButton.Text = '3 fatores';
            app.BoundsTable = uitable(app.UIFigure);
            app.BoundsTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.BoundsTable.RowName = {};
            app.BoundsTable.ColumnEditable = true;
            app.InitialConditionsTable = uitable(app.UIFigure);
            app.InitialConditionsTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.InitialConditionsTable.RowName = {};
            app.InitialConditionsTable.ColumnEditable = true;
            app.ColeInputsAquiTextAreaLabel = uilabel(app.UIFigure);
            app.ColeInputsAquiTextAreaLabel.Text = 'Cole Inputs Aqui';
            app.PasteTextArea = uitextarea(app.UIFigure);
            app.PasteTextArea.ValueChangedFcn = createCallbackFcn(app, @PasteTextAreaValueChanged, true);
            app.ModelCoefficientsTable = uitable(app.UIFigure);
            app.ModelCoefficientsTable.ColumnName = '';
            app.ModelCoefficientsTable.RowName = {};
            app.CoeficientesdoModeloLabel = uilabel(app.UIFigure);
            app.CoeficientesdoModeloLabel.Text = 'Coeficientes do Modelo';
            app.FTestTable = uitable(app.UIFigure);
            app.FTestTable.ColumnName = '';
            app.FTestTable.RowName = {};
            app.FTestTable.Tooltip = {'a'};
            app.MaximizarButton = uibutton(app.UIFigure, 'push');
            app.MaximizarButton.ButtonPushedFcn = createCallbackFcn(app, @MaximizarButtonPushed, true);
            app.MaximizarButton.Visible = 'off';
            app.MaximizarButton.Text = 'Maximizar';
            app.MinimizarButton = uibutton(app.UIFigure, 'push');
            app.MinimizarButton.ButtonPushedFcn = createCallbackFcn(app, @MinimizarButtonPushed, true);
            app.MinimizarButton.Visible = 'off';
            app.MinimizarButton.Text = 'Minimizar';
            app.TipodeModeloButtonGroup = uibuttongroup(app.UIFigure);
            app.TipodeModeloButtonGroup.Title = 'Tipo de Modelo';
            app.LinearButton = uiradiobutton(app.TipodeModeloButtonGroup);
            app.LinearButton.Text = 'Linear';
            app.LinearButton.Value = true;
            app.QuadButton = uiradiobutton(app.TipodeModeloButtonGroup);
            app.QuadButton.Text = 'Quadrático';
            app.MaiorInclinaoButton = uibutton(app.UIFigure, 'push');
            app.MaiorInclinaoButton.ButtonPushedFcn = createCallbackFcn(app, @MaiorInclinaoButtonPushed, true);
            app.MaiorInclinaoButton.Visible = 'off';
            app.MaiorInclinaoButton.Text = 'Maior Inclinação';
            app.NmerodePontosSpinner = uispinner(app.UIFigure);
            app.NmerodePontosSpinner.Limits = [5 20];
            app.NmerodePontosSpinner.RoundFractionalValues = 'on';
            app.NmerodePontosSpinner.Visible = 'off';
            app.NmerodePontosSpinner.Value = 5;
            app.PlotarButton = uibutton(app.UIFigure, 'push');
            app.PlotarButton.ButtonPushedFcn = createCallbackFcn(app, @PlotarButtonPushed, true);
            app.PlotarButton.Visible = 'off';
            app.PlotarButton.Text = 'Plotar';
            app.AnovaLabel = uilabel(app.UIFigure);
            app.AnovaLabel.Visible = 'off';
            app.AnovaLabel.Text = 'Anova';
            app.optimizeTable = uitable(app.UIFigure);
            app.optimizeTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.optimizeTable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_regressao

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end